classdef emClarityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        emC_app                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        AssetsTab                       matlab.ui.container.Tab
        TabGroup2_5                     matlab.ui.container.TabGroup
        MoviesTab                       matlab.ui.container.Tab
        TextArea_5                      matlab.ui.control.TextArea
        TiltseriesTab                   matlab.ui.container.Tab
        TextArea_14                     matlab.ui.control.TextArea
        tbl_TiltSeries                  matlab.ui.control.Table
        ImporttiltseriesButton          matlab.ui.control.Button
        PixelSizeAEditFieldLabel        matlab.ui.control.Label
        PixelSizeAEditField             matlab.ui.control.NumericEditField
        VoltagekeVEditFieldLabel        matlab.ui.control.Label
        VoltagekeVEditField             matlab.ui.control.NumericEditField
        CsmmEditFieldLabel              matlab.ui.control.Label
        CsmmEditField                   matlab.ui.control.NumericEditField
        AmpllitudeContrastLabel         matlab.ui.control.Label
        AmplitudeFractionEditField      matlab.ui.control.NumericEditField
        TargetDefocusnmLabel            matlab.ui.control.Label
        TargetDefocusEditField          matlab.ui.control.NumericEditField
        FiducialDiameterAEditFieldLabel  matlab.ui.control.Label
        FiducialDiameterAEditField      matlab.ui.control.NumericEditField
        DeleteButton                    matlab.ui.control.Button
        SuperResolutionCheckBox         matlab.ui.control.CheckBox
        TextArea_15                     matlab.ui.control.TextArea
        OneoverCosinedoseCheckBox       matlab.ui.control.CheckBox
        StartingTiltAngleEditFieldLabel  matlab.ui.control.Label
        StartingTiltAngleEditField      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        DoseSymmetricStepEditFieldLabel  matlab.ui.control.Label
        DoseSymmetricStepEditField      matlab.ui.control.NumericEditField
        DoseSymmetricGroupSizeEditFieldLabel  matlab.ui.control.Label
        DoseSymmetricGroupSizeEditField  matlab.ui.control.NumericEditField
        IncludezeroinfirstgroupCheckBox  matlab.ui.control.CheckBox
        ParticlePositionsTab            matlab.ui.container.Tab
        TextArea_7                      matlab.ui.control.TextArea
        DVolumesTab                     matlab.ui.container.Tab
        TextArea_8                      matlab.ui.control.TextArea
        EtcTab_2                        matlab.ui.container.Tab
        ActionsTab                      matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        AlignMoviesTab                  matlab.ui.container.Tab
        TextArea_13                     matlab.ui.control.TextArea
        AlignTiltseriesTab              matlab.ui.container.Tab
        TextArea_12                     matlab.ui.control.TextArea
        FindCTFTab                      matlab.ui.container.Tab
        EditField2                      matlab.ui.control.NumericEditField
        RunCTFButton                    matlab.ui.control.Button
        FindParticlesTab                matlab.ui.container.Tab
        TextArea_10                     matlab.ui.control.TextArea
        DvolumesTab                     matlab.ui.container.Tab
        TextArea_9                      matlab.ui.control.TextArea
        ResultsTab                      matlab.ui.container.Tab
        TabGroup2_4                     matlab.ui.container.TabGroup
        AlignMoviesTab_2                matlab.ui.container.Tab
        AlignTiltseriesTab_2            matlab.ui.container.Tab
        FindCTFTab_2                    matlab.ui.container.Tab
        FindParticlesTab_2              matlab.ui.container.Tab
        ResEtcTab                       matlab.ui.container.Tab
        SettingsTab                     matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        RunTab                          matlab.ui.container.Tab
        nCpuCoresEditFieldLabel         matlab.ui.control.Label
        nCpuCoresEditField              matlab.ui.control.NumericEditField
        nGPUsEditFieldLabel             matlab.ui.control.Label
        nGPUsEditField                  matlab.ui.control.NumericEditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        MaskingTab                      matlab.ui.container.Tab
        UseShapeMaskCheckBox            matlab.ui.control.CheckBox
        MaskShapeDropDownLabel          matlab.ui.control.Label
        MaskShapeDropDown               matlab.ui.control.DropDown
        Ali_mRadiusxyzLabel             matlab.ui.control.Label
        Ali_mRadius_X_EditField         matlab.ui.control.NumericEditField
        Cls_mRadiusxyzLabel             matlab.ui.control.Label
        Cls_mRadius_X_EditField         matlab.ui.control.NumericEditField
        Cls_mRadius_Y_EditField         matlab.ui.control.NumericEditField
        Cls_mRadius_Z_EditField         matlab.ui.control.NumericEditField
        ParticleradiusxyzLabel          matlab.ui.control.Label
        particleRadius_X_EditField      matlab.ui.control.NumericEditField
        AlignmentTab                    matlab.ui.container.Tab
        Ali_samplingRateEditFieldLabel  matlab.ui.control.Label
        Ali_samplingRateEditField       matlab.ui.control.NumericEditField
        Cls_samplingRateEditFieldLabel  matlab.ui.control.Label
        Cls_samplingRateEditField       matlab.ui.control.NumericEditField
        Tmp_samplingRateEditFieldLabel  matlab.ui.control.Label
        Tmp_samplingRateEditField       matlab.ui.control.NumericEditField
        OutofPlaneRangeEditFieldLabel   matlab.ui.control.Label
        RawOutofPlaneRangeEditField     matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        RawOutOfPlaneStepEditField      matlab.ui.control.NumericEditField
        RawInPlaneRangeEditFieldLabel   matlab.ui.control.Label
        RawInfPlaneRangeEditField       matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        RawInPlaneStepEditField         matlab.ui.control.NumericEditField
        RawAlignmentSymmetryDropDownLabel  matlab.ui.control.Label
        RawAlignmentSymmetryDropDown    matlab.ui.control.DropDown
        RawInPlaneRangeEditFieldLabel_2  matlab.ui.control.Label
        RawInfPlaneRangeEditField_2     matlab.ui.control.NumericEditField
        OutofPlaneRangeEditFieldLabel_2  matlab.ui.control.Label
        ClsOutofPlaneRangeEditField     matlab.ui.control.NumericEditField
        StepLabel_2                     matlab.ui.control.Label
        ClsOutOfPlaneStepEditField      matlab.ui.control.NumericEditField
        StepEditField_2Label_2          matlab.ui.control.Label
        ClsInPlaneStepEditField         matlab.ui.control.NumericEditField
        ClsAlignmentSymmetryLabel       matlab.ui.control.Label
        ClsAlignmentSymmetryDropDown    matlab.ui.control.DropDown
        TmpInPlaneRangeEditFieldLabel   matlab.ui.control.Label
        TmpInfPlaneRangeEditField       matlab.ui.control.NumericEditField
        OutofPlaneRangeEditFieldLabel_3  matlab.ui.control.Label
        TmpOutofPlaneRangeEditField     matlab.ui.control.NumericEditField
        StepLabel_3                     matlab.ui.control.Label
        TmpOutOfPlaneStepEditField      matlab.ui.control.NumericEditField
        StepEditField_2Label_3          matlab.ui.control.Label
        TmpInPlaneStepEditField         matlab.ui.control.NumericEditField
        TmpAlignmentSymmetryDropDownLabel  matlab.ui.control.Label
        TmpAlignmentSymmetryDropDown    matlab.ui.control.DropDown
        ClassificationTab               matlab.ui.container.Tab
        DoClassificationCheckBox        matlab.ui.control.CheckBox
        Pca_maxEigsEditFieldLabel       matlab.ui.control.Label
        Pca_maxEigsEditField            matlab.ui.control.NumericEditField
        Pca_clustersEditField_2Label    matlab.ui.control.Label
        Pca_clustersEditField_2         matlab.ui.control.EditField
        Pca_scaleSpaceEditFieldLabel    matlab.ui.control.Label
        Pca_scaleSpaceEditField         matlab.ui.control.EditField
        Pca_randSubsetEditFieldLabel    matlab.ui.control.Label
        Pca_randSubsetEditField         matlab.ui.control.NumericEditField
        PcaGpuPullEditFieldLabel        matlab.ui.control.Label
        PcaGpuPullEditField             matlab.ui.control.NumericEditField
        Pca_coeffsEditFieldLabel        matlab.ui.control.Label
        Pca_coeffsEditField             matlab.ui.control.EditField
        particleRadius_Y_EditField      matlab.ui.control.NumericEditField
        particleRadius_Z_EditField      matlab.ui.control.NumericEditField
        particleRadiusEditFieldLabel    matlab.ui.control.Label
        particleRadiusEditField         matlab.ui.control.NumericEditField
        Ali_mRadius_Z_EditField         matlab.ui.control.NumericEditField
        Ali_mRadius_Y_EditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        % Fixed parameters
        emC_CACHE = '~/.emClarityCache';
        
        % For tilt-series
        nTiltSeries = 0 % Description
        nTiltSeriesAdded = 0;
        dTiltSeries_idx = [0,0];
        
        % For project metaData
        recentProjectFile;
        emC; % Datastructure
        
    
        subTomoMeta=testProject;

        fastScratchDisk;

        % Number of GPUS
        nGPUs=2;
        nCpuCores=9;

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%    Mask parameters    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % The particle radius in x,y,z Angstrom, smallest value to contain particle. 
        % For particles in a lattice, neighboring particles can be used in alignment
        % by specifying a larger mask size, but this paramter must correspond to your
        % target, a cetral hexamer of capsid proteins for example.
        particleRadius=[160,160,160];
        % Estimated particle mass in megaDa
        particleMass=4;

        Ali_mType='sphere';
        Cls_mType='sphere';

        % For special cases where repeated motifs are present which might cause one
        % subtomo to drift to a neighbor. This allows a larger alignment mask to be used
        % for the rotational search (Ali_m...) but limits the translational peak search.
        Peak_mType='none';
        Peak_mRadius=[100,100,100];

        % mask radius and center - and center in Angstrom. Mask size is determined 
        % large enough to contain delocalized signal, proper apodization, and to 
        % avoid wraparound error in cross-correlation.
        Ali_mRadius=[180,180,180];
        Ali_mCenter=[0,0,0];
        Cls_mRadius=[180,180,180];
        Cls_mCenter=[0,0,0 ];

        
        % Sampling rate
        Ali_samplingRate=4;
        Cls_samplingRate=4;


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%% Tomo-constrained projection refinement parameters    %%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % I advise to avoid using this experimental feature for now.
        tomoCprDefocusRefine=0;
        tomoCprDefocusRange=500e-9; 
        tomoCprDefocusStep=20e-9;


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%    subTomogram alignment           %%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        Raw_className=0;
        % Second row specifies C1 symmetry
        Raw_classes_odd=[0;1.*ones(2,1)];
        Raw_classes_eve=[0;1.*ones(2,1)];
        
        Raw_angleSearch=[15,5,0,0];
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%    Template matching parameters    %%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        Tmp_samplingRate=6;
        Tmp_threshold=400;
        Tmp_angleSearch=[180,15,180,15,0];
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%    Class reference   %%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        Cls_className=9;
        Cls_classes_odd=[1:9;1.*ones(1,9)];
        Cls_classes_eve=[1:9;1.*ones(1,9)];

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%    FSC Paramters    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % On/Off anisotropic SSNR calc
        flgCones=1;

        % B-factor applied to weighted averages and refs. Should be < 20. Can be a vector
        % where the 2:end positions generate independent maps at that sharpening 
        % when "avg paramN.m N FinalAlignment is run.
        Fsc_bfactor=10;
        
        % For very tightly packed subTomos set to 1 to avoid mixing halfsets
        % form overlaping peripheral density.
        fscGoldSplitOnTomos=0;


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%    Classification Paramters    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % On/Off classification. This must be on when "avg paramN.m N RawAlignment"
        % is run at the begining of a cycle where classification is to be run.
        flgClassify=0;
        
        % List of different cluster sizes to try, eg [3;4]
        Pca_clusters=[2,3];
        
        % Maximum number of eigenvalues/vectors to save
        Pca_maxEigs=36;
        
        % Different resolution bands to run PCA on. Not all need to be used for subsequent
        % clustering. (Angstrom)
        pcaScaleSpace=[8,14,21];
        
        % Random subset of particles used to reduce the burden of PCA
        % This is ignored if flgPcaFull is true in the call to "pca"
        Pca_randSubset=0;
        
        % Different ranges of coefficients to use in the clustering. At times, the 
        % missing wedge can be a strong feature, such that ignoring the first few 
        % eigen values can be usefule. [2:40 ; 6;40 ; 10:40]
        % Each row must have the same number of entries, and there must be a row 
        % for each scale space, even if it is all zeros.
        Pca_coeffs=[7:11;7:11;7:11];
        
        % The number of subtomos to process at once before pulling tempDataMatrix off 
        % the gpu and into main memory.
        PcaGpuPull=1200;

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%% Parameters for CTF all si (meters, volts)%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        %%%%%%%%%%   Microscope parameters     %%%%%%%%%%
        
        % Of the data saved in fixed stacks - MUST match header
        PIXEL_SIZE=2.17;
        % Currently any super-resolution data is cropped in Fourier Space after alignment
        % allowing for finer sampling when interpolating the stacks, while then 
        % filtering out noise due to aliasing.
        SuperResolution=0;
        % Spherical abberation
        Cs=2.7;
        % Accelerat;ing voltage
        VOLTAGE=300;
        % Percent amplitude contrast
        AMPCONT=0.10;
        
        % search range - generally safe to test a wide range
        defEstimate=3500;
        defWindow=2000;
        % The PS is considered from the lower resolution inflection point
        % past the first zero to this cutoff resolution
        defCutOff=7;

        % Gold fiducial diameter
        beadDiameter=100;

        % Boolean: If a graduated dose scheme is used, where applied dose is ~ Thickness (1/cos(tiltAngle))
        oneOverCosineDose=0;
        % Degrees: first tilt collected
        startingAngle=30;
        % String: Direction after first tilt (pos or neg)
        startingDirection=neg;
        % Boolean: Group size on either side of zero, e.g. 2 (with pos) would give, 0, 3, 6, -3, -6, 9, ...
        doseSymmetricIncrement=0;
        % Float: Dose applied per tilt (at zero degrees)
        doseAtMinTilt=1.46;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
    end
    
    properties (Access = protected)
        
    end
    
    properties (Access = public)
      
        dTiltSeries = { 'TiltSeries name', 1.0, 3838, 3710, 41, 1.35, 300.0, 2.7, 0.10, 1200, 100 }
    end
    
    methods (Access = private)
        
        function results = WriteParamterFile(app, callingFunction)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tbl_TiltSeries.Data = app.dTiltSeries;
            app.recentProjectFile = sprintf('%s/recentProjects.mat',app.emC_CACHE);
%             app.recentProjectFile
            
            if isfile(app.recentProjectFile)   
                load(app.recentProjectFile,'recentProjects');
                app.tbl_recentProjects.Data = recentProjects;
            else
                system(sprintf('mkdir -p %s',app.emC_CACHE));
                app.tbl_recentProjects.Data = {'No recent projects!'};
            end
        end

        % Button pushed function: ImporttiltseriesButton
        function ImporttiltseriesButtonPushed(app, event)
            % TODO add a check on duplicate files
            [ fileName, pathName ] = uigetfile('*.mrc; *.st; *.fixed','MultiSelect','on');

            fprintf('There are currently %d tilt-series\n',app.nTiltSeries);cd 
            
            if ~iscell(fileName)
                fileName = {fileName};
            end
   
            for iFile = 1:length(fileName)
                fprintf('\n%s %s\n',fileName{iFile}, pathName);
                app.nTiltSeries = app.nTiltSeries + 1;
                app.nTiltSeriesAdded = app.nTiltSeriesAdded + 1;
                app.dTiltSeries{ app.nTiltSeries, 1 } =  fileName{iFile};
                app.dTiltSeries{ app.nTiltSeries, 2 } =  app.nTiltSeriesAdded;
                % Pull in info from the header
                iHeader = getHeader(MRCImage(fullfile(pathName,fileName{iFile})));
                app.dTiltSeries{ app.nTiltSeries, 3 } = iHeader.nX;
                app.dTiltSeries{ app.nTiltSeries, 4 } = iHeader.nY;
                app.dTiltSeries{ app.nTiltSeries, 5 } = iHeader.nZ;
                app.dTiltSeries{ app.nTiltSeries, 6 } = app.PixelSizeAEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 7 } = app.VoltagekeVEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 8 } = app.CsmmEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 9 } = app.AmplitudeFractionEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 10 } = app.TargetDefocusEditField.Value;
                app.dTiltSeries{ app.nTiltSeries, 11 } = app.FiducialDiameterAEditField.Value;
                % Thow warning if pixel size doesn't matchheader
                
            end   
            
            fprintf('There are currently %d tilt-series\n',app.nTiltSeries);
            
            % Update the table
            app.tbl_TiltSeries.Data = app.dTiltSeries;
            
        end

        % Callback function
        function AddNewProjectName(app, event)
            value = app.NewProjectNameEditField.Value;
            
            % Check that the project is unique
            title = 'Confirm New Project';
            if isfile(sprintf('%s.mat',value))
                msg = sprintf('A project of this name (%s) already exists, overwrite?',value);
                opts = {'Overwrite','Re-enter a new name'};
            else
                msg = sprintf('Create a new refinement project with the name %s?.',value);
                opts = {'Create New', 'Cancel'};
            end
            
            f = uifigure;        
            selection = uiconfirm(f,msg,title,...
           'Options',opts);
            
            %%% TODO add a method to update the recent projects list
            switch selection
                case 'Create New'
                    app.emC = struct();
                    subTomoMeta = app.emC;
                    save(sprintf('%s.mat',value),'subTomoMeta');
                    tmpPrjName = sprintf('%s/%s.mat',pwd, value);
                    if isfile(app.recentProjectFile)
                        
                        load(app.recentProjectFile,'recentProjects');
           
                        tmpProjects = cell(length(recentProjects)+1,1);
                        tmpProjects{1} = tmpPrjName;
                        tmpProjects{1}
                        for iPrj = 1:length(recentProjects)
                            tmpProjects{iPrj+1} = recentProjects{iPrj};
                            tmpProjects{iPrj+1}
                        end
                        recentProjects = tmpProjects;
                        clear tmpProjects tmpPrjName
                    else
                        recentProjects = {tmpPrjName};
                    end
                    
                    save(app.recentProjectFile,'recentProjects','-mat');
                    
                case 'Overwrite'
                    system(sprintf('mv %s.mat %s_bckup.mat',value, value));
                    app.emC = struct();
                    subTomoMeta = app.emC;
                    save(sprintf('%s.mat',value),'subTomoMeta');

                otherwise
                % Nothing happens, just go back to the main
            end
            
            delete(f);

        end

        % Cell selection callback: tbl_TiltSeries
        function tbl_TiltSeriesCellSelection(app, event)
            app.DeleteButton.Enable = 1;
            app.dTiltSeries_idx = event.Indices;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % What is the best was to do this?
            rowIDX = app.dTiltSeries_idx(:,1);
            newTable = cell(app.nTiltSeries - length(rowIDX),11);
            nRow = 1;
            for iRow = 1:app.nTiltSeries
               if ~ismember(iRow,rowIDX)
                   for iCol = 1:11
                    newTable{nRow,iCol} = app.dTiltSeries{iRow,iCol};
                   end
                   nRow = nRow + 1;
               end
            end
            
            app.dTiltSeries = newTable;
            clear newTable
            app.nTiltSeries = app.nTiltSeries - length(rowIDX);
            app.DeleteButton.Enable = 0;
            % Update the table
            app.tbl_TiltSeries.Data = app.dTiltSeries;
        end

        % Button pushed function: RunCTFButton
        function RunCTFButtonPushed(app, event)
            % Generate a paramter file
            
            % Run CTF estimate and then CTF refine
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.emC_app = uifigure('Visible', 'off');
            app.emC_app.Name = 'emClarity';
            app.TabGroup = uitabgroup(app.emC_app);
            app.Tab = uitab(app.TabGroup);
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'enhanced macromolecular Classification and Alignment '; 'for '; 'high-resolution in situ tomography'};
            app.TextArea_2 = uitextarea(app.Tab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'emClarity'};
            app.AssetsTab = uitab(app.TabGroup);
            app.AssetsTab.Title = 'Assets';
            app.TabGroup2_5 = uitabgroup(app.AssetsTab);
            app.MoviesTab = uitab(app.TabGroup2_5);
            app.MoviesTab.Title = 'Movies';
            app.TextArea_5 = uitextarea(app.MoviesTab);
            app.TextArea_5.Value = {'Movie alignments are expected to be performed ahead of entering the emClarity workflow. For now, there are just too many crazy naming, numbering schemes to invest the time in getting this to work.'; ''; 'I recommend using unblur (CITE) and there is an example script that I use for my own work provided in the emClarity/docs/scripts folder.'};
            app.TiltseriesTab = uitab(app.TabGroup2_5);
            app.TiltseriesTab.Title = ' Tilt-series';
            app.TextArea_14 = uitextarea(app.TiltseriesTab);
            app.TextArea_14.Editable = 'off';
            app.TextArea_14.Value = {'Imaging parameters'};
            app.tbl_TiltSeries = uitable(app.TiltseriesTab);
            app.tbl_TiltSeries.ColumnName = {'data Name'; 'ID'; 'nX'; 'nY'; 'nZ'; 'pixelSize'; 'kV'; 'Cs'; 'Amp Ratio'; 'target defocus'; 'fiducial diameter'};
            app.tbl_TiltSeries.RowName = {};
            app.tbl_TiltSeries.ColumnSortable = [true true false false true true false false false false];
            app.tbl_TiltSeries.ColumnEditable = [false false false false false false false false false false false];
            app.tbl_TiltSeries.CellSelectionCallback = createCallbackFcn(app, @tbl_TiltSeriesCellSelection, true);
            app.ImporttiltseriesButton = uibutton(app.TiltseriesTab, 'push');
            app.ImporttiltseriesButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttiltseriesButtonPushed, true);
            app.ImporttiltseriesButton.Text = 'Import tilt-series';
            app.PixelSizeAEditFieldLabel = uilabel(app.TiltseriesTab);
            app.PixelSizeAEditFieldLabel.Text = 'Pixel Size ( A )';
            app.PixelSizeAEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.PixelSizeAEditField.Value = 1.35;
            app.VoltagekeVEditFieldLabel = uilabel(app.TiltseriesTab);
            app.VoltagekeVEditFieldLabel.Text = 'Voltage ( keV )';
            app.VoltagekeVEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.VoltagekeVEditField.ValueDisplayFormat = '%3d';
            app.VoltagekeVEditField.Value = 300;
            app.CsmmEditFieldLabel = uilabel(app.TiltseriesTab);
            app.CsmmEditFieldLabel.Text = 'Cs ( mm )';
            app.CsmmEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.CsmmEditField.Limits = [0 5];
            app.CsmmEditField.ValueDisplayFormat = '%1.1f';
            app.CsmmEditField.Value = 2.7;
            app.AmpllitudeContrastLabel = uilabel(app.TiltseriesTab);
            app.AmpllitudeContrastLabel.Text = 'Ampllitude Contrast';
            app.AmplitudeFractionEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.AmplitudeFractionEditField.Limits = [0 1];
            app.AmplitudeFractionEditField.ValueDisplayFormat = '%1.1f';
            app.AmplitudeFractionEditField.Value = 0.1;
            app.TargetDefocusnmLabel = uilabel(app.TiltseriesTab);
            app.TargetDefocusnmLabel.Text = 'Target Defocus ( nm )';
            app.TargetDefocusEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.TargetDefocusEditField.Limits = [-20000 200000];
            app.TargetDefocusEditField.ValueDisplayFormat = '%3.6f';
            app.TargetDefocusEditField.Value = 1000;
            app.FiducialDiameterAEditFieldLabel = uilabel(app.TiltseriesTab);
            app.FiducialDiameterAEditFieldLabel.Text = 'Fiducial Diameter ( A )';
            app.FiducialDiameterAEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.FiducialDiameterAEditField.Value = 100;
            app.DeleteButton = uibutton(app.TiltseriesTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.SuperResolutionCheckBox = uicheckbox(app.TiltseriesTab);
            app.SuperResolutionCheckBox.Text = 'SuperResolution';
            app.TextArea_15 = uitextarea(app.TiltseriesTab);
            app.TextArea_15.Editable = 'off';
            app.TextArea_15.Value = {'Tilting parameters'};
            app.OneoverCosinedoseCheckBox = uicheckbox(app.TiltseriesTab);
            app.OneoverCosinedoseCheckBox.Text = 'One over Cosine dose';
            app.StartingTiltAngleEditFieldLabel = uilabel(app.TiltseriesTab);
            app.StartingTiltAngleEditFieldLabel.Text = 'Starting Tilt Angle';
            app.StartingTiltAngleEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.StartingTiltAngleEditField.Limits = [-90 90];
            app.Label = uilabel(app.TiltseriesTab);
            app.Label.Text = '( - )';
            app.Switch = uiswitch(app.TiltseriesTab, 'toggle');
            app.Switch.Items = {'Starting Direction', '(  +  )'};
            app.Switch.Value = '(  +  )';
            app.DoseSymmetricStepEditFieldLabel = uilabel(app.TiltseriesTab);
            app.DoseSymmetricStepEditFieldLabel.Text = 'Dose Symmetric Step';
            app.DoseSymmetricStepEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.DoseSymmetricStepEditField.Value = 3;
            app.DoseSymmetricGroupSizeEditFieldLabel = uilabel(app.TiltseriesTab);
            app.DoseSymmetricGroupSizeEditFieldLabel.Text = 'Dose Symmetric Group Size';
            app.DoseSymmetricGroupSizeEditField = uieditfield(app.TiltseriesTab, 'numeric');
            app.DoseSymmetricGroupSizeEditField.Value = 2;
            app.IncludezeroinfirstgroupCheckBox = uicheckbox(app.TiltseriesTab);
            app.IncludezeroinfirstgroupCheckBox.Text = {'Include zero in  first group'; ''};
            app.IncludezeroinfirstgroupCheckBox.Value = true;
            app.ParticlePositionsTab = uitab(app.TabGroup2_5);
            app.ParticlePositionsTab.Title = 'Particle Positions';
            app.TextArea_7 = uitextarea(app.ParticlePositionsTab);
            app.TextArea_7.Value = {'This will be for import/ export to other packages'};
            app.DVolumesTab = uitab(app.TabGroup2_5);
            app.DVolumesTab.Title = '3D Volumes';
            app.TextArea_8 = uitextarea(app.DVolumesTab);
            app.TextArea_8.Value = {'Use this both for importing a reference and defining it.'; ''; ''};
            app.EtcTab_2 = uitab(app.TabGroup2_5);
            app.EtcTab_2.Title = 'Etc';
            app.ActionsTab = uitab(app.TabGroup);
            app.ActionsTab.Title = 'Actions';
            app.TabGroup2_2 = uitabgroup(app.ActionsTab);
            app.AlignMoviesTab = uitab(app.TabGroup2_2);
            app.AlignMoviesTab.Title = 'Align Movies';
            app.TextArea_13 = uitextarea(app.AlignMoviesTab);
            app.TextArea_13.Editable = 'off';
            app.TextArea_13.Value = {'Movie alignments are expected to be performed ahead of entering the emClarity workflow. For now, there are just too many crazy naming, numbering schemes to invest the time in getting this to work.'; ''; 'I recommend using unblur (CITE) and there is an example script that I use for my own work provided in the emClarity/docs/scripts folder.'};
            app.AlignTiltseriesTab = uitab(app.TabGroup2_2);
            app.AlignTiltseriesTab.Title = 'Align Tilt-series';
            app.TextArea_12 = uitextarea(app.AlignTiltseriesTab);
            app.TextArea_12.Value = {'TODO, use this area to set up the project dir?'; ''; 'Could optionally call etomo or the runAuto script I''ve written. This is a bit weird to have listed prior to the action.'};
            app.FindCTFTab = uitab(app.TabGroup2_2);
            app.FindCTFTab.Title = 'Find CTF';
            app.EditField2 = uieditfield(app.FindCTFTab, 'numeric');
            app.RunCTFButton = uibutton(app.FindCTFTab, 'push');
            app.RunCTFButton.ButtonPushedFcn = createCallbackFcn(app, @RunCTFButtonPushed, true);
            app.RunCTFButton.Text = 'Run CTF';
            app.FindParticlesTab = uitab(app.TabGroup2_2);
            app.FindParticlesTab.Title = 'Find Particles';
            app.TextArea_10 = uitextarea(app.FindParticlesTab);
            app.TextArea_10.Value = {'Text box'};
            app.DvolumesTab = uitab(app.TabGroup2_2);
            app.DvolumesTab.Title = '3D volumes';
            app.TextArea_9 = uitextarea(app.DvolumesTab);
            app.TextArea_9.Value = {'Want to be able to reScale'; ''; 'Bandpass'; ''; 'Combine'; ''; 'Mask'};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup2_4 = uitabgroup(app.ResultsTab);
            app.AlignMoviesTab_2 = uitab(app.TabGroup2_4);
            app.AlignMoviesTab_2.Title = 'Align Movies';
            app.AlignTiltseriesTab_2 = uitab(app.TabGroup2_4);
            app.AlignTiltseriesTab_2.Title = 'Align Tilt-series';
            app.FindCTFTab_2 = uitab(app.TabGroup2_4);
            app.FindCTFTab_2.Title = 'Find CTF';
            app.FindParticlesTab_2 = uitab(app.TabGroup2_4);
            app.FindParticlesTab_2.Title = 'Find Particles';
            app.ResEtcTab = uitab(app.TabGroup2_4);
            app.ResEtcTab.Title = 'ResEtc';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup6 = uitabgroup(app.SettingsTab);
            app.RunTab = uitab(app.TabGroup6);
            app.RunTab.Title = 'Run';
            app.nCpuCoresEditFieldLabel = uilabel(app.RunTab);
            app.nCpuCoresEditFieldLabel.Text = 'nCpuCores';
            app.nCpuCoresEditField = uieditfield(app.RunTab, 'numeric');
            app.nCpuCoresEditField.Value = 4;
            app.nGPUsEditFieldLabel = uilabel(app.RunTab);
            app.nGPUsEditFieldLabel.Text = 'nGPUs';
            app.nGPUsEditField = uieditfield(app.RunTab, 'numeric');
            app.nGPUsEditField.Value = 2;
            app.ProjectNameEditFieldLabel = uilabel(app.RunTab);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.RunTab, 'text');
            app.ProjectNameEditField.Value = 'emClarity_tutorial';
            app.MaskingTab = uitab(app.TabGroup6);
            app.MaskingTab.Title = 'Masking';
            app.UseShapeMaskCheckBox = uicheckbox(app.MaskingTab);
            app.UseShapeMaskCheckBox.Text = 'Use Shape Mask';
            app.UseShapeMaskCheckBox.Value = true;
            app.MaskShapeDropDownLabel = uilabel(app.MaskingTab);
            app.MaskShapeDropDownLabel.Text = 'Mask Shape';
            app.MaskShapeDropDown = uidropdown(app.MaskingTab);
            app.MaskShapeDropDown.Items = {'Spherical', 'Cylindrical', 'Rectangular'};
            app.MaskShapeDropDown.Value = 'Spherical';
            app.Ali_mRadiusxyzLabel = uilabel(app.MaskingTab);
            app.Ali_mRadiusxyzLabel.Text = 'Ali_mRadius: x,y,z';
            app.Ali_mRadius_X_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.Ali_mRadius_X_EditField.Limits = [10 300];
            app.Ali_mRadius_X_EditField.Value = 100;
            app.Ali_mRadius_Y_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.Ali_mRadius_Y_EditField.Limits = [10 300];
            app.Ali_mRadius_Y_EditField.Value = 100;
            app.Ali_mRadius_Z_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.Ali_mRadius_Z_EditField.Limits = [10 300];
            app.Ali_mRadius_Z_EditField.Value = 100;
            app.Cls_mRadiusxyzLabel = uilabel(app.MaskingTab);
            app.Cls_mRadiusxyzLabel.Text = 'Cls_mRadius: x,y,z';
            app.Cls_mRadius_X_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.Cls_mRadius_X_EditField.Limits = [10 300];
            app.Cls_mRadius_X_EditField.Value = 100;
            app.Cls_mRadius_Y_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.Cls_mRadius_Y_EditField.Limits = [10 300];
            app.Cls_mRadius_Y_EditField.Value = 100;
            app.Cls_mRadius_Z_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.Cls_mRadius_Z_EditField.Limits = [10 300];
            app.Cls_mRadius_Z_EditField.Value = 100;
            app.ParticleradiusxyzLabel = uilabel(app.MaskingTab);
            app.ParticleradiusxyzLabel.Text = 'Particle radius: xyz';
            app.particleRadius_X_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.particleRadius_X_EditField.Limits = [10 300];
            app.particleRadius_X_EditField.Value = 100;
            app.particleRadius_Y_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.particleRadius_Y_EditField.Limits = [10 300];
            app.particleRadius_Y_EditField.Value = 100;
            app.particleRadius_Z_EditField = uieditfield(app.MaskingTab, 'numeric');
            app.particleRadius_Z_EditField.Limits = [10 300];
            app.particleRadius_Z_EditField.Value = 100;
            app.particleRadiusEditFieldLabel = uilabel(app.MaskingTab);
            app.particleRadiusEditFieldLabel.Text = 'Particle Mass MDa';
            app.particleRadiusEditField = uieditfield(app.MaskingTab, 'numeric');
            app.particleRadiusEditField.Limits = [1 8];
            app.particleRadiusEditField.Value = 1;
            app.AlignmentTab = uitab(app.TabGroup6);
            app.AlignmentTab.Title = 'Alignment';
            app.Ali_samplingRateEditFieldLabel = uilabel(app.AlignmentTab);
            app.Ali_samplingRateEditFieldLabel.Text = 'Ali_samplingRate';
            app.Ali_samplingRateEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.Ali_samplingRateEditField.Limits = [1 8];
            app.Ali_samplingRateEditField.Value = 1;
            app.Cls_samplingRateEditFieldLabel = uilabel(app.AlignmentTab);
            app.Cls_samplingRateEditFieldLabel.Text = 'Cls_samplingRate';
            app.Cls_samplingRateEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.Cls_samplingRateEditField.Limits = [1 8];
            app.Cls_samplingRateEditField.Value = 1;
            app.Tmp_samplingRateEditFieldLabel = uilabel(app.AlignmentTab);
            app.Tmp_samplingRateEditFieldLabel.Text = 'Tmp_samplingRate';
            app.Tmp_samplingRateEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.Tmp_samplingRateEditField.Limits = [1 8];
            app.Tmp_samplingRateEditField.Value = 1;
            app.OutofPlaneRangeEditFieldLabel = uilabel(app.AlignmentTab);
            app.OutofPlaneRangeEditFieldLabel.Text = 'Out of Plane Range :';
            app.RawOutofPlaneRangeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.RawOutofPlaneRangeEditField.Limits = [0.05 180];
            app.RawOutofPlaneRangeEditField.Value = 10;
            app.StepLabel = uilabel(app.AlignmentTab);
            app.StepLabel.Text = 'Step :';
            app.RawOutOfPlaneStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.RawOutOfPlaneStepEditField.Limits = [0.05 180];
            app.RawOutOfPlaneStepEditField.Value = 5;
            app.RawInPlaneRangeEditFieldLabel = uilabel(app.AlignmentTab);
            app.RawInPlaneRangeEditFieldLabel.Text = 'In Plane Range :';
            app.RawInfPlaneRangeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.RawInfPlaneRangeEditField.Limits = [0.05 180];
            app.RawInfPlaneRangeEditField.Value = 10;
            app.StepEditField_2Label = uilabel(app.AlignmentTab);
            app.StepEditField_2Label.Text = 'Step :';
            app.RawInPlaneStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.RawInPlaneStepEditField.Limits = [0.05 180];
            app.RawInPlaneStepEditField.Value = 10;
            app.RawAlignmentSymmetryDropDownLabel = uilabel(app.AlignmentTab);
            app.RawAlignmentSymmetryDropDownLabel.Text = 'RawAlignment Symmetry';
            app.RawAlignmentSymmetryDropDown = uidropdown(app.AlignmentTab);
            app.RawAlignmentSymmetryDropDown.Items = {'C1', 'C2', 'C3', 'C4', 'C5', 'C6'};
            app.RawAlignmentSymmetryDropDown.Value = 'C1';
            app.RawInPlaneRangeEditFieldLabel_2 = uilabel(app.AlignmentTab);
            app.RawInPlaneRangeEditFieldLabel_2.Text = 'In Plane Range :';
            app.RawInfPlaneRangeEditField_2 = uieditfield(app.AlignmentTab, 'numeric');
            app.RawInfPlaneRangeEditField_2.Limits = [0.05 180];
            app.RawInfPlaneRangeEditField_2.Value = 10;
            app.OutofPlaneRangeEditFieldLabel_2 = uilabel(app.AlignmentTab);
            app.OutofPlaneRangeEditFieldLabel_2.Text = 'Out of Plane Range :';
            app.ClsOutofPlaneRangeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.ClsOutofPlaneRangeEditField.Limits = [0.05 180];
            app.ClsOutofPlaneRangeEditField.Value = 10;
            app.StepLabel_2 = uilabel(app.AlignmentTab);
            app.StepLabel_2.Text = 'Step :';
            app.ClsOutOfPlaneStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.ClsOutOfPlaneStepEditField.Limits = [0.05 180];
            app.ClsOutOfPlaneStepEditField.Value = 5;
            app.StepEditField_2Label_2 = uilabel(app.AlignmentTab);
            app.StepEditField_2Label_2.Text = 'Step :';
            app.ClsInPlaneStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.ClsInPlaneStepEditField.Limits = [0.05 180];
            app.ClsInPlaneStepEditField.Value = 10;
            app.ClsAlignmentSymmetryLabel = uilabel(app.AlignmentTab);
            app.ClsAlignmentSymmetryLabel.Text = 'ClsAlignment Symmetry';
            app.ClsAlignmentSymmetryDropDown = uidropdown(app.AlignmentTab);
            app.ClsAlignmentSymmetryDropDown.Items = {'C1', 'C2', 'C3', 'C4', 'C5', 'C6'};
            app.ClsAlignmentSymmetryDropDown.Value = 'C1';
            app.TmpInPlaneRangeEditFieldLabel = uilabel(app.AlignmentTab);
            app.TmpInPlaneRangeEditFieldLabel.Text = 'In Plane Range :';
            app.TmpInfPlaneRangeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.TmpInfPlaneRangeEditField.Limits = [0.05 180];
            app.TmpInfPlaneRangeEditField.Value = 10;
            app.OutofPlaneRangeEditFieldLabel_3 = uilabel(app.AlignmentTab);
            app.OutofPlaneRangeEditFieldLabel_3.Text = 'Out of Plane Range :';
            app.TmpOutofPlaneRangeEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.TmpOutofPlaneRangeEditField.Limits = [0.05 180];
            app.TmpOutofPlaneRangeEditField.Value = 10;
            app.StepLabel_3 = uilabel(app.AlignmentTab);
            app.StepLabel_3.Text = 'Step :';
            app.TmpOutOfPlaneStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.TmpOutOfPlaneStepEditField.Limits = [0.05 180];
            app.TmpOutOfPlaneStepEditField.Value = 5;
            app.StepEditField_2Label_3 = uilabel(app.AlignmentTab);
            app.StepEditField_2Label_3.Text = 'Step :';
            app.TmpInPlaneStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.TmpInPlaneStepEditField.Limits = [0.05 180];
            app.TmpInPlaneStepEditField.Value = 10;
            app.TmpAlignmentSymmetryDropDownLabel = uilabel(app.AlignmentTab);
            app.TmpAlignmentSymmetryDropDownLabel.Text = 'TmpAlignment Symmetry';
            app.TmpAlignmentSymmetryDropDown = uidropdown(app.AlignmentTab);
            app.TmpAlignmentSymmetryDropDown.Items = {'C1', 'C2', 'C3', 'C4', 'C5', 'C6'};
            app.TmpAlignmentSymmetryDropDown.Value = 'C1';
            app.ClassificationTab = uitab(app.TabGroup6);
            app.ClassificationTab.Title = 'Classification';
            app.DoClassificationCheckBox = uicheckbox(app.ClassificationTab);
            app.DoClassificationCheckBox.Text = 'Do Classification';
            app.Pca_maxEigsEditFieldLabel = uilabel(app.ClassificationTab);
            app.Pca_maxEigsEditFieldLabel.Text = 'Pca_maxEigs';
            app.Pca_maxEigsEditField = uieditfield(app.ClassificationTab, 'numeric');
            app.Pca_maxEigsEditField.Value = 36;
            app.Pca_clustersEditField_2Label = uilabel(app.ClassificationTab);
            app.Pca_clustersEditField_2Label.Text = 'Pca_clusters';
            app.Pca_clustersEditField_2 = uieditfield(app.ClassificationTab, 'text');
            app.Pca_clustersEditField_2.Value = '2,4,6';
            app.Pca_scaleSpaceEditFieldLabel = uilabel(app.ClassificationTab);
            app.Pca_scaleSpaceEditFieldLabel.Text = ' Pca_scaleSpace';
            app.Pca_scaleSpaceEditField = uieditfield(app.ClassificationTab, 'text');
            app.Pca_scaleSpaceEditField.Value = '12,20,42';
            app.Pca_randSubsetEditFieldLabel = uilabel(app.ClassificationTab);
            app.Pca_randSubsetEditFieldLabel.Text = 'Pca_randSubset';
            app.Pca_randSubsetEditField = uieditfield(app.ClassificationTab, 'numeric');
            app.Pca_randSubsetEditField.Limits = [0 Inf];
            app.PcaGpuPullEditFieldLabel = uilabel(app.ClassificationTab);
            app.PcaGpuPullEditFieldLabel.Text = 'PcaGpuPull';
            app.PcaGpuPullEditField = uieditfield(app.ClassificationTab, 'numeric');
            app.PcaGpuPullEditField.Limits = [100 10000];
            app.PcaGpuPullEditField.Value = 1200;
            app.Pca_coeffsEditFieldLabel = uilabel(app.ClassificationTab);
            app.Pca_coeffsEditFieldLabel.Text = 'Pca_coeffs';
            app.Pca_coeffsEditField = uieditfield(app.ClassificationTab, 'text');
            app.Pca_coeffsEditField.Value = '7:11,7:11,7:11';
            % Show the figure after all components are created
            app.emC_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emClarityApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.emC_app)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.emC_app)
        end
    end
end